classdef OWIWkspaceVisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlotInverseKinematicsButton    matlab.ui.control.Button
        PlotWorkspaceButton            matlab.ui.control.Button
        EndEffectorAngleDegreesSlider  matlab.ui.control.Slider
        EndEffectorAngleDegreesSliderLabel  matlab.ui.control.Label
        OriginalBisection2             matlab.ui.control.UIAxes
        UserControlledBisection2       matlab.ui.control.UIAxes
        OriginalBisection              matlab.ui.control.UIAxes
        UserControlledBisection        matlab.ui.control.UIAxes
        UserControlled                 matlab.ui.control.UIAxes
        Original                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Inputs:
        %   endEffectorAngle - angle of the end effector
        % Outputs:
        % workspacePlotsOutputs - Bisection of workspace & 3D workspace plots
        function workspacePlotsOutputs1 = workspacePlotsBackward(app,endEffectorAngleInput)
            
            endEffectorAngleInput = endEffectorAngleInput*pi/180;
            l1 = 0.09;
            l2 = 0.125;
            l3 = 0.09;
            Joint1 = linspace(0,180,50)*pi/180;
            Joint2 = linspace(-150,150,50)*pi/180;
            Joint3 = linspace(-60,60,50)*pi/180;
            [theta1,theta2,theta3] = meshgrid(Joint1,Joint2,Joint3);
            
            x1 = l1.*cos(theta1);
            y1 = l1.*sin(theta1)+0.05;
            
            x2 = l1.*cos(theta1)+l2.*cos(theta1+theta2);
            y2 = l1.*sin(theta1)+l2.*sin(theta1+theta2)+0.05;
            
            x3 = l1.*cos(theta1)+l2.*cos(theta1+theta2)+l3*cos(theta1+theta2+theta3);
            y3 = l1.*sin(theta1)+l2.*sin(theta1+theta2)+l3*sin(theta1+theta2+theta3)+0.05;
            
            restrictions1 = [];
            restrictions2 = [];
            restrictions3 = [];
            
            x1Inter = x1(:);
            y1Inter = y1(:);
            x2Inter = x2(:);
            y2Inter = y2(:);
            xNew = x3(:);
            yNew = y3(:);
            
            for i = 1:length(x1Inter)
                x1Line = linspace(0,x1Inter(i),5);
                y1Line = linspace(0.05,y1Inter(i),5);
                checkRestrictions = (x1Line>=-0.07) & (x1Line<=0.18) & (y1Line<0.04);
                restrictions1 = [restrictions1;any(checkRestrictions == 1)];
            end
            for i = 1:length(x2Inter)
                x2Line = linspace(x1Inter(i),x2Inter(i),10);
                y2Line = linspace(y1Inter(i),y2Inter(i),10);
                checkRestrictions = (x2Line>=-0.07) & (x2Line<=0.18) & (y2Line<0.04);
                restrictions2 = [restrictions2;any(checkRestrictions == 1)];
            end
            for i = 1:length(xNew)
                x3Line = linspace(x2Inter(i),xNew(i),10);
                y3Line = linspace(y2Inter(i),yNew(i),10);
                checkRestrictions = (x3Line>=-0.07) & (x3Line<=0.18) & (y3Line<0.04);
                restrictions3 = [restrictions3;any(checkRestrictions == 1)];
            end
            
            endEffectorAngle = theta1 + theta2 + theta3;
            checkRestrictionAngles = abs(endEffectorAngle - endEffectorAngleInput);
            restrictionAngle = checkRestrictionAngles > 0.2;
            restrictionAngle = restrictionAngle(:);
            check1 = restrictions1;
            check2 = restrictions2;
            check3 = restrictions3;
            xNoCollision = xNew.*~(restrictions1 | restrictions2 | restrictions3 | restrictionAngle);
            yNoCollision = yNew.*~(restrictions1 | restrictions2 | restrictions3 | restrictionAngle);
            %xNoCollision = xNew.*~restrictionAngle;
            %yNoCollision = yNew.*~restrictionAngle;
            %xNoCollision = xNew;
            %yNoCollision = yNew;
            nahGood = find(yNoCollision==0);
            yNoCollision(nahGood) = [];
            xNoCollision(nahGood) = [];
            
            angle = linspace(-180,180,361)*pi/180;
            magWorkspace = sqrt(xNoCollision.^2 + yNoCollision.^2);
            angleWorkspace = atan2(yNoCollision,xNoCollision);
            minMagReduced = [];
            maxMagReduced = [];
            minAngleReduced = [];
            maxAngleReduced = [];
            
            iTrack = [];
            for i = 1:length(angle(1,:))
               iTrack = [iTrack,angle(1,i)];
               angleLocation = find(abs(angle(1,i)-angleWorkspace) < 0.05);
               [minMag,Imin] = min(abs(magWorkspace(angleLocation)));
               [maxMag,Imax] = max(abs(magWorkspace(angleLocation)));
               minMagReduced = [minMagReduced,minMag];
               maxMagReduced = [maxMagReduced,maxMag];
               minAngleReduced = [minAngleReduced,angleWorkspace(angleLocation(Imin))];
               maxAngleReduced = [maxAngleReduced,angleWorkspace(angleLocation(Imax))];
            end    
            
             xmin = abs(smooth(minMagReduced)').*cos(smooth(minAngleReduced)');
             ymin = abs(smooth(minMagReduced)').*sin(smooth(minAngleReduced)');
             xmax = abs(smooth(maxMagReduced)').*cos(smooth(maxAngleReduced)');
             ymax = abs(smooth(maxMagReduced)').*sin(smooth(maxAngleReduced)');
            
            [temp,xminNegHalfPi] = min(abs(minAngleReduced(:)+pi/2));
            [temp,xminPosHalfPi] = min(abs(minAngleReduced(:)-pi/2));
            [temp,xmaxNegHalfPi] = min(abs(maxAngleReduced(:)+pi/2));
            [temp,xmaxPosHalfPi] = min(abs(maxAngleReduced(:)-pi/2));
            
            xminHalf = xmin(1,xminNegHalfPi:xminPosHalfPi);
            yminHalf = ymin(1,xminNegHalfPi:xminPosHalfPi);
            xmaxHalf = xmax(1,xmaxNegHalfPi:xmaxPosHalfPi);
            ymaxHalf = ymax(1,xmaxNegHalfPi:xmaxPosHalfPi);
            
            x3dmin = xminHalf.*cos(angle(136:226)');
            y3dmin = xminHalf.*sin(angle(136:226)');
            z3dmin = repmat(yminHalf,91,1);
            x3dmax = xmaxHalf.*cos(angle(136:226)');
            y3dmax = xmaxHalf.*sin(angle(136:226)');
            z3dmax = repmat(ymaxHalf,91,1);
            
%            % workspace3DPlot = figure();
%             surf(app.UserControlled,x3dmin,y3dmin,z3dmin,'LineStyle','none')
%             hold on
%             surf(app.UserControlled,x3dmax,y3dmax,z3dmax,'LineStyle','none')
%             alpha 0.5
%             xlabel(app.UserControlled,'x')
%             ylabel(app.UserControlled,'y')
%             zlabel(app.UserControlled,'z')
%             hold off
%             
%             %Bisection = figure();
%             plot(app.UserControlledBisection,xNoCollision,yNoCollision,'.', 'LineStyle', 'none')
%             hold on
%             plot(app.UserControlledBisection,xmin,ymin)
%             hold on
%             plot(app.UserControlledBisection,xmax,ymax)
%             xlabel(app.UserControlledBisection,'x')
%             ylabel(app.UserControlledBisection,'z')
%             title(app.UserControlledBisection,'OWI Workspace Cross Section Joint + Self Collsion Constraints')
%             hold off
            
            workspacePlotsOutputs1 = struct;
            workspacePlotsOutputs1.x3dmin = x3dmin;
            workspacePlotsOutputs1.y3dmin = y3dmin;
            workspacePlotsOutputs1.z3dmin = z3dmin;
            workspacePlotsOutputs1.x3dmax = x3dmax;
            workspacePlotsOutputs1.y3dmax = y3dmax;
            workspacePlotsOutputs1.z3dmax = z3dmax;
            workspacePlotsOutputs1.xNoCollision = xNoCollision;
            workspacePlotsOutputs1.yNoCollision = yNoCollision;
            workspacePlotsOutputs1.xmin = xmin;
            workspacePlotsOutputs1.ymin = ymin;
            workspacePlotsOutputs1.xmax = xmax;
            workspacePlotsOutputs1.ymax = ymax;
            workspacePlotsOutputs1.xminHalf = xminHalf;
            workspacePlotsOutputs1.yminHalf = yminHalf;
            workspacePlotsOutputs1.xmaxHalf = xmaxHalf;
            workspacePlotsOutputs1.ymaxHalf = ymaxHalf;            
            
            %workspacePlotsOutputs = [workspace3DPlot,Bisection];
        end

        
        % Inputs:
        %   endEffectorAngle - angle of the end effector
        % Outputs:
        % workspacePlotsOutputs - Bisection of workspace & 3D workspace plots
        function workspacePlotsOutputs2 = workspacePlotsForward(app,endEffectorAngleInput)
        
            endEffectorAngleInput = endEffectorAngleInput*pi/180;
            l1 = 0.09;
            l2 = 0.125;
            l3 = 0.09;
            Joint1 = linspace(0,180,100)*pi/180;
            Joint2 = linspace(-150,150,100)*pi/180;
            Joint3 = linspace(-60,60,100)*pi/180;
            [theta1,theta2,theta3] = meshgrid(Joint1,Joint2,Joint3);
            
            x3 = l1.*cos(theta1)+l2.*cos(theta1+theta2)+l3*cos(theta1+theta2+theta3);
            y3 = l1.*sin(theta1)+l2.*sin(theta1+theta2)+l3*sin(theta1+theta2+theta3)+0.05;
            
            restrictions1 = [];
            restrictions2 = [];
            restrictions3 = [];
            
            xNew = x3(:);
            yNew = y3(:);
            
            endEffectorAngle = theta1 + theta2 + theta3;
            checkRestrictionAngles = abs(endEffectorAngle - endEffectorAngleInput);
            restrictionAngle = checkRestrictionAngles > 0.2;
            restrictionAngle = restrictionAngle(:);
            check1 = restrictions1;
            check2 = restrictions2;
            check3 = restrictions3;
            
            xNoCollision = xNew.*~restrictionAngle;
            yNoCollision = yNew.*~restrictionAngle;
            nahGood = find(yNoCollision==0);
            yNoCollision(nahGood) = [];
            xNoCollision(nahGood) = [];
            
            angle = linspace(-180,180,361)*pi/180;
            magWorkspace = sqrt(xNoCollision.^2 + yNoCollision.^2);
            angleWorkspace = atan2(yNoCollision,xNoCollision);
            minMagReduced = [];
            maxMagReduced = [];
            minAngleReduced = [];
            maxAngleReduced = [];
            
            iTrack = [];
            for i = 1:length(angle(1,:))
               iTrack = [iTrack,angle(1,i)];
               angleLocation = find(abs(angle(1,i)-angleWorkspace) < 0.05);
               [minMag,Imin] = min(abs(magWorkspace(angleLocation)));
               [maxMag,Imax] = max(abs(magWorkspace(angleLocation)));
               minMagReduced = [minMagReduced,minMag];
               maxMagReduced = [maxMagReduced,maxMag];
               minAngleReduced = [minAngleReduced,angleWorkspace(angleLocation(Imin))];
               maxAngleReduced = [maxAngleReduced,angleWorkspace(angleLocation(Imax))];
            end    
            
             xmin = abs(smooth(minMagReduced)').*cos(smooth(minAngleReduced)');
             ymin = abs(smooth(minMagReduced)').*sin(smooth(minAngleReduced)');
             xmax = abs(smooth(maxMagReduced)').*cos(smooth(maxAngleReduced)');
             ymax = abs(smooth(maxMagReduced)').*sin(smooth(maxAngleReduced)');
            
            [temp,xminNegHalfPi] = min(abs(minAngleReduced(:)+pi/2));
            [temp,xminPosHalfPi] = min(abs(minAngleReduced(:)-pi/2));
            [temp,xmaxNegHalfPi] = min(abs(maxAngleReduced(:)+pi/2));
            [temp,xmaxPosHalfPi] = min(abs(maxAngleReduced(:)-pi/2));
            
            xminHalf = xmin(1,xminNegHalfPi:xminPosHalfPi);
            yminHalf = ymin(1,xminNegHalfPi:xminPosHalfPi);
            xmaxHalf = xmax(1,xmaxNegHalfPi:xmaxPosHalfPi);
            ymaxHalf = ymax(1,xmaxNegHalfPi:xmaxPosHalfPi);
            
            x3dmin = xminHalf.*cos(angle(226:361)');
            y3dmin = xminHalf.*sin(angle(226:361)');
            z3dmin = repmat(yminHalf,136,1);
            x3dmax = xmaxHalf.*cos(angle(226:361)');
            y3dmax = xmaxHalf.*sin(angle(226:361)');
            z3dmax = repmat(ymaxHalf,136,1);
            
            workspacePlotsOutputs2 = struct;
            workspacePlotsOutputs2.x3dmin2 = x3dmin;
            workspacePlotsOutputs2.y3dmin2 = y3dmin;
            workspacePlotsOutputs2.z3dmin2 = z3dmin;
            workspacePlotsOutputs2.x3dmax2 = x3dmax;
            workspacePlotsOutputs2.y3dmax2 = y3dmax;
            workspacePlotsOutputs2.z3dmax2 = z3dmax;
            workspacePlotsOutputs2.xminHalf = xminHalf;
            workspacePlotsOutputs2.yminHalf = yminHalf;
            workspacePlotsOutputs2.xmaxHalf = xmaxHalf;
            workspacePlotsOutputs2.ymaxHalf = ymaxHalf;
            
            
%             hold on
%             surf(app.UserControlled,x3dmin,y3dmin,z3dmin,'LineStyle','none')
%             hold on
%             surf(app.UserControlled,x3dmax,y3dmax,z3dmax,'LineStyle','none')
%             alpha 0.5
%             xlabel(app.UserControlled,'x')
%             ylabel(app.UserControlled,'y')
%             zlabel(app.UserControlled,'z')
            
            x3dmin = xminHalf.*cos(angle(1:136)');
            y3dmin = xminHalf.*sin(angle(1:136)');
            z3dmin = repmat(yminHalf,136,1);
            x3dmax = xmaxHalf.*cos(angle(1:136)');
            y3dmax = xmaxHalf.*sin(angle(1:136)');
            z3dmax = repmat(ymaxHalf,136,1);
            
%             %workspace3DPlot = figure();
%             surf(app.UserControlled,x3dmin,y3dmin,z3dmin,'LineStyle','none')
%             hold on
%             surf(app.UserControlled,x3dmax,y3dmax,z3dmax,'LineStyle','none')
%             alpha 0.5
%             xlabel(app.UserControlled,'x')
%             ylabel(app.UserControlled,'y')
%             zlabel(app.UserControlled,'z')
%             hold off 
%             
%             %Bisection = figure();
%             plot(app.UserControlledBisection,xNoCollision,yNoCollision,'.', 'LineStyle', 'none')
%             hold on
%             plot(app.UserControlledBisection,xmin,ymin)
%             hold on
%             plot(app.UserControlledBisection,xmax,ymax)
%             xlabel(app.UserControlledBisection,'x')
%             ylabel(app.UserControlledBisection,'z')
%             title(app.UserControlledBisection,'OWI Workspace Cross Section Joint + Self Collsion Constraints')
%             hold off
           
            workspacePlotsOutputs2.x3dmin = x3dmin;
            workspacePlotsOutputs2.y3dmin = y3dmin;
            workspacePlotsOutputs2.z3dmin = z3dmin;
            workspacePlotsOutputs2.x3dmax = x3dmax;
            workspacePlotsOutputs2.y3dmax = y3dmax;
            workspacePlotsOutputs2.z3dmax = z3dmax;
            workspacePlotsOutputs2.xNoCollision = xNoCollision;
            workspacePlotsOutputs2.yNoCollision = yNoCollision;
            workspacePlotsOutputs2.xmin = xmin;
            workspacePlotsOutputs2.ymin = ymin;
            workspacePlotsOutputs2.xmax = xmax;
            workspacePlotsOutputs2.ymax = ymax;
            
            
            %workspacePlotsOutputs = [workspace3DPlot,Bisection];
        end

        
        function ikMatrix = ikMultiple(app,ik,endEffector, positions, weights,initialGuess,limitJointChange,angleInput)
            currentRobotJConfig = [];
            for i = 1:length(positions)
                if i == 1
                    taskInit = trvec2tform(positions(i,:))*axang2tform([0 1 0 angleInput*pi/180]);
                    currentRobotJConfig = [currentRobotJConfig,ik(endEffector, taskInit, weights, initialGuess)];
                    currentRobotJConfig(:,i) = limitingJoints(app,currentRobotJConfig(:,i),limitJointChange);
            %         [n,solInfo] = ik(endEffector, taskInit, weights, currentRobotJConfig);
            %         n
            %         positions(i,:)
                else
                    taskInit = trvec2tform(positions(i,:))*axang2tform([0 1 0 2*pi]);
                    currentRobotJConfig = [currentRobotJConfig,ik(endEffector, taskInit, weights, currentRobotJConfig(:,i-1))];
                    currentRobotJConfig(:,i) = limitingJoints(app,currentRobotJConfig(:,i),limitJointChange);
            %         [n,solInfo] = ik(endEffector, taskInit, weights, currentRobotJConfig(:,i-1));
            %         n
            %         positions(i,:)
                end
            ikMatrix = currentRobotJConfig;
            end
        end

                
        function output = limitingJoints(app,currentRobotJConfig,limitJointChange) 
            limitJoint = limitJointChange.Bounds;
            for i = 1:length(limitJoint(:,1))
                if currentRobotJConfig(i,1) < limitJoint(i,1)
                    currentRobotJConfig(i,1) = limitJoint(i,1);
                end
                if currentRobotJConfig(i,1) > limitJoint(i,2)
                    currentRobotJConfig(i,1) = limitJoint(i,2);
                end
                output = currentRobotJConfig;
            end
        end
        
        function results = func5(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotWorkspaceButton
        function PlotWorkspaceButtonPushed(app, event)
            cla(app.OriginalBisection)
            cla(app.UserControlledBisection)
            cla(app.UserControlled)
            cla(app.Original)
            
            open_system('owiNew.slx')

            global robot
            [robot,importInfo] = importrobot(gcs);
            robot.DataFormat = 'column';
            addVisual(robot.Bodies{1,1},"Mesh",'BaseFinal.STL')
            addVisual(robot.Bodies{1,2},"Mesh",'motorBase2.STL')
            addVisual(robot.Bodies{1,3},"Mesh",'armLinkF.STL')
            addVisual(robot.Bodies{1,4},"Mesh",'dualMotorLinkF.STL')
            addVisual(robot.Bodies{1,5},"Mesh",'gripperBase.STL')
            addVisual(robot.Bodies{1,6},"Mesh",'GripperCombineF.STL')
            endEffector = "Body6";  
                        
            numJoints = numel(homeConfiguration(robot));
            % Constraints
            limitJointChange = constraintJointBounds(robot);
            % Inverse Kinematics
            ik = inverseKinematics('RigidBodyTree', robot);
            ik.SolverParameters.AllowRandomRestart = true;
            weights = [0 0 0 1 1 1];
            clear endEffectorAngleInput workspacePlotsOutputs2 workspacePlotsOutputs1 xPosition2min zPosition2min yPosition2min xPosition2max yPosition2max zPosition2max
            clear xPosition1min  zPosition1min yPosition1min xPosition1max yPosition1max zPosition1max ikMatrix1min ikMatrix2min ikMatrix1max ikMatrix2max 
            global endEffectorAngleInput workspacePlotsOutputs2 workspacePlotsOutputs1 xPosition2min zPosition2min yPosition2min xPosition2max yPosition2max zPosition2max
            global xPosition1min  zPosition1min yPosition1min xPosition1max yPosition1max zPosition1max ikMatrix1min ikMatrix2min ikMatrix1max ikMatrix2max 
                        endEffectorAngleInput = app.EndEffectorAngleDegreesSlider.Value;

            workspacePlotsOutputs2 = workspacePlotsForward(app,endEffectorAngleInput);
            workspacePlotsOutputs1 = workspacePlotsBackward(app,endEffectorAngleInput);
            
            xPosition2min = workspacePlotsOutputs2.xminHalf;
            zPosition2min = workspacePlotsOutputs2.yminHalf;
            yPosition2min = zeros(length(zPosition2min(:,1)),length(zPosition2min(1,:)));            

            xPosition2max = workspacePlotsOutputs2.xmaxHalf;
            zPosition2max = workspacePlotsOutputs2.ymaxHalf;
            yPosition2max = zeros(length(zPosition2max(:,1)),length(zPosition2max(1,:)));              
            
            xPosition1min = workspacePlotsOutputs1.xminHalf;
            zPosition1min = workspacePlotsOutputs1.yminHalf;
            yPosition1min = zeros(length(zPosition1min(:,1)),length(zPosition1min(1,:)));            
            
            xPosition1max = workspacePlotsOutputs1.xmaxHalf;
            zPosition1max = workspacePlotsOutputs1.ymaxHalf;
            yPosition1max = zeros(length(zPosition1max(:,1)),length(zPosition1max(1,:))); 
            
            initialGuess = [    0.0075;...
                1.6242;...
               -1.2175;...
                0.3992];
            ikMatrix1min = ikMultiple(app,ik,endEffector, [-xPosition1min',yPosition1min',zPosition1min'], weights,initialGuess,limitJointChange,endEffectorAngleInput);
            ikMatrix2min = ikMultiple(app,ik,endEffector, [xPosition2min',yPosition2min',zPosition2min'], weights,initialGuess,limitJointChange,endEffectorAngleInput);
            ikMatrix1max = ikMultiple(app,ik,endEffector, [-xPosition1max',yPosition1max',zPosition1max'], weights,initialGuess,limitJointChange,endEffectorAngleInput);
            ikMatrix2max = ikMultiple(app,ik,endEffector, [xPosition2max',yPosition2max',zPosition2max'], weights,initialGuess,limitJointChange,endEffectorAngleInput);
% 
%             framerate = 100;
%             r = rateControl(framerate);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Plotting Workspace %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
            OriFront = load('OriFront.mat');
            OriBack = load('OriBack.mat');
            OriFront2 = load('OriFront2.mat');
            
            surf(app.Original,-OriFront2.OriFront2.x3dmin,OriFront2.OriFront2.y3dmin,OriFront2.OriFront2.z3dmin,'LineStyle','none')
            hold(app.Original,'on')
            surf(app.Original,-OriFront2.OriFront2.x3dmax,OriFront2.OriFront2.y3dmax,OriFront2.OriFront2.z3dmax,'LineStyle','none')
            alpha(app.Original,0.5)
            xlabel(app.Original,'x')
            ylabel(app.Original,'y')
            zlabel(app.Original,'z')
            hold(app.Original,'on')
            
            surf(app.Original,-OriFront.OriFront.x3dmin,OriFront.OriFront.y3dmin,OriFront.OriFront.z3dmin,'LineStyle','none')
            hold(app.Original,'on')
            surf(app.Original,-OriFront.OriFront.x3dmax,OriFront.OriFront.y3dmax,OriFront.OriFront.z3dmax,'LineStyle','none')
            alpha(app.Original,0.5)
            xlabel(app.Original,'x')
            ylabel(app.Original,'y')
            zlabel(app.Original,'z')
            hold(app.Original,'on')
            
            surf(app.Original,-OriBack.OriBack.x3dmin,OriBack.OriBack.y3dmin,OriBack.OriBack.z3dmin,'LineStyle','none')
            hold(app.Original,'on')
            surf(app.Original,-OriBack.OriBack.x3dmax,OriBack.OriBack.y3dmax,OriBack.OriBack.z3dmax,'LineStyle','none')
            alpha(app.Original,0.5)
            xlabel(app.Original,'x')
            ylabel(app.Original,'y')
            zlabel(app.Original,'z')
            hold(app.Original,'on')
            
            plot(app.OriginalBisection,OriBack.OriBack.xNoCollision,OriBack.OriBack.yNoCollision,'.', 'LineStyle', 'none')
            hold(app.OriginalBisection,'on')
            plot(app.OriginalBisection,OriBack.OriBack.xmin,OriBack.OriBack.ymin)
            hold(app.OriginalBisection,'on')
            plot(app.OriginalBisection,OriBack.OriBack.xmax,OriBack.OriBack.ymax)
            xlabel(app.OriginalBisection,'x')
            ylabel(app.OriginalBisection,'z')
            title(app.OriginalBisection,'Back Bisection OWI Workspace')
            hold(app.OriginalBisection,'off')
             
            plot(app.OriginalBisection2,OriFront.OriFront.xNew,OriFront.OriFront.yNew,'.', 'LineStyle', 'none')
            hold(app.OriginalBisection2,'on')
            plot(app.OriginalBisection2,OriFront.OriFront.xmin,OriFront.OriFront.ymin)
            hold(app.OriginalBisection2,'on')
            plot(app.OriginalBisection2,OriFront.OriFront.xmax,OriFront.OriFront.ymax)
            xlabel(app.OriginalBisection2,'x')
            ylabel(app.OriginalBisection2,'z')
            title(app.OriginalBisection2,'Front Bisection OWI Workspace')
            hold(app.OriginalBisection2,'off')
            
            %workspace3DPlot = figure();
            surf(app.UserControlled,-workspacePlotsOutputs2.x3dmin,workspacePlotsOutputs2.y3dmin,workspacePlotsOutputs2.z3dmin,'LineStyle','none')
            hold(app.UserControlled,'on')
            surf(app.UserControlled,-workspacePlotsOutputs2.x3dmax,workspacePlotsOutputs2.y3dmax,workspacePlotsOutputs2.z3dmax,'LineStyle','none')
            alpha(app.UserControlled,0.5)
            xlabel(app.UserControlled,'x')
            ylabel(app.UserControlled,'y')
            zlabel(app.UserControlled,'z')
            hold(app.UserControlled,'on')
           
            surf(app.UserControlled,-workspacePlotsOutputs2.x3dmin2,workspacePlotsOutputs2.y3dmin2,workspacePlotsOutputs2.z3dmin2,'LineStyle','none')
            hold(app.UserControlled,'on')
            surf(app.UserControlled,-workspacePlotsOutputs2.x3dmax2,workspacePlotsOutputs2.y3dmax2,workspacePlotsOutputs2.z3dmax2,'LineStyle','none')
            alpha(app.UserControlled,0.5)
            xlabel(app.UserControlled,'x')
            ylabel(app.UserControlled,'y')
            zlabel(app.UserControlled,'z')
            hold(app.UserControlled,'on')
            
            % workspace3DPlot = figure();
            surf(app.UserControlled,-workspacePlotsOutputs1.x3dmin,workspacePlotsOutputs1.y3dmin,workspacePlotsOutputs1.z3dmin,'LineStyle','none')
            hold(app.UserControlled,'on')
            surf(app.UserControlled,-workspacePlotsOutputs1.x3dmax,workspacePlotsOutputs1.y3dmax,workspacePlotsOutputs1.z3dmax,'LineStyle','none')
            alpha(app.UserControlled,0.5)
            xlabel(app.UserControlled,'x')
            ylabel(app.UserControlled,'y')
            zlabel(app.UserControlled,'z')
            hold(app.UserControlled,'on')

            %Bisection = figure();
            plot(app.UserControlledBisection,workspacePlotsOutputs1.xNoCollision,workspacePlotsOutputs1.yNoCollision,'.', 'LineStyle', 'none')
            hold(app.UserControlledBisection,'on')
            plot(app.UserControlledBisection,workspacePlotsOutputs1.xmin,workspacePlotsOutputs1.ymin)
            hold(app.UserControlledBisection,'on')
            plot(app.UserControlledBisection,workspacePlotsOutputs1.xmax,workspacePlotsOutputs1.ymax)
            xlabel(app.UserControlledBisection,'x')
            ylabel(app.UserControlledBisection,'z')
            title(app.UserControlledBisection,'Back Bisection OWI Workspace')
            hold(app.UserControlledBisection,'off')

             %Bisection = figure();
            plot(app.UserControlledBisection2,workspacePlotsOutputs2.xNoCollision,workspacePlotsOutputs2.yNoCollision,'.', 'LineStyle', 'none')
            hold(app.UserControlledBisection2,'on')
            plot(app.UserControlledBisection2,workspacePlotsOutputs2.xmin,workspacePlotsOutputs2.ymin)
            hold(app.UserControlledBisection2,'on')
            plot(app.UserControlledBisection2,workspacePlotsOutputs2.xmax,workspacePlotsOutputs2.ymax)
            xlabel(app.UserControlledBisection2,'x')
            ylabel(app.UserControlledBisection2,'z')
            title(app.UserControlledBisection2,'Front Bisection OWI Workspace')
            hold(app.UserControlledBisection2,'off')


        end

        % Callback function
        function ImportRobotSimulinkButtonPushed(app, event)
            open_system('owiNew.slx')
        end

        % Button pushed function: PlotInverseKinematicsButton
        function PlotInverseKinematicsButtonPushed(app, event)
             global endEffectorAngleInput workspacePlotsOutputs2 workspacePlotsOutputs1 xPosition2min zPosition2min yPosition2min xPosition2max yPosition2max zPosition2max
            global xPosition1min  zPosition1min yPosition1min xPosition1max yPosition1max zPosition1max ikMatrix1min ikMatrix2min ikMatrix1max ikMatrix2max robot
            
            framerate = 100;
             r = rateControl(framerate);
            
            figure()
            surf(-workspacePlotsOutputs2.x3dmin,workspacePlotsOutputs2.y3dmin,workspacePlotsOutputs2.z3dmin,'LineStyle','none','FaceAlpha',0.5,'FaceColor',"flat")
            hold on 
            surf(-workspacePlotsOutputs2.x3dmax,workspacePlotsOutputs2.y3dmax,workspacePlotsOutputs2.z3dmax,'LineStyle','none','FaceAlpha',0.5,'FaceColor',"flat")
            xlabel('x')
            ylabel('y')
            zlabel('z')
            axis([-.33,.33,-.33,.33,-.15,.4])
            view(1,1);
            hold on 
           
            surf(-workspacePlotsOutputs2.x3dmin2,workspacePlotsOutputs2.y3dmin2,workspacePlotsOutputs2.z3dmin2,'LineStyle','none','FaceAlpha',0.5,'FaceColor',"flat")
            hold on 
            surf(-workspacePlotsOutputs2.x3dmax2,workspacePlotsOutputs2.y3dmax2,workspacePlotsOutputs2.z3dmax2,'LineStyle','none','FaceAlpha',0.5,'FaceColor',"flat")
            hold on 
            
            % workspace3DPlot = figure();
            surf(-workspacePlotsOutputs1.x3dmin,workspacePlotsOutputs1.y3dmin,workspacePlotsOutputs1.z3dmin,'LineStyle','none','FaceAlpha',0.5,'FaceColor',"flat")
            hold on 
            surf(-workspacePlotsOutputs1.x3dmax,workspacePlotsOutputs1.y3dmax,workspacePlotsOutputs1.z3dmax,'LineStyle','none','FaceAlpha',0.5,'FaceColor',"flat")
            hold on 
            
            scatter3(-xPosition1min',yPosition1min',zPosition1min');
            hold on 
            scatter3(xPosition2min',yPosition2min',zPosition2min');
            hold on 
            
            scatter3(-xPosition1max',yPosition1max',zPosition1max');
            hold on 
            scatter3(xPosition2max',yPosition2max',zPosition2max');
            hold on 
            
            show(robot, ikMatrix1min(:,1), 'PreservePlot', false,'visuals','on');
            hold on 
            for i = 2:length(ikMatrix1min(1,:))
            	show(robot,ikMatrix1min(:,i), 'PreservePlot', false,'visuals','on');%FastUpdate=true )
                waitfor(r);
            end
           hold on 
            show(robot, ikMatrix2min(:,1), 'PreservePlot', false,'visuals','on');
            hold on 
            for i = 2:length(ikMatrix2min(1,:))
                show(robot,ikMatrix2min(:,i), 'PreservePlot', false,'visuals','on');%FastUpdate=true 
                waitfor(r);
            end     
           hold on 
            show(robot, ikMatrix1max(:,1), 'PreservePlot', false,'visuals','on');
            hold on 
            for i = 2:length(ikMatrix1max(1,:))
                show(robot,ikMatrix1max(:,i), 'PreservePlot', false,'visuals','on');%FastUpdate=true 
                waitfor(r);
            end     
           hold on 
            show(robot, ikMatrix2max(:,1), 'PreservePlot', false,'visuals','on');
            hold on 
            for i = 2:length(ikMatrix2max(1,:))
                show(robot,ikMatrix2max(:,i), 'PreservePlot', false,'visuals','on');%FastUpdate=true 
                waitfor(r);
            end     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Original = uiaxes(app.UIFigure);
            title(app.Original, '3D Workspace')
            xlabel(app.Original, 'X')
            ylabel(app.Original, 'Y')
            zlabel(app.Original, 'Z')
            app.UserControlled = uiaxes(app.UIFigure);
            title(app.UserControlled, '3D Workspace User Selected End Effector Angle')
            xlabel(app.UserControlled, 'X')
            ylabel(app.UserControlled, 'Y')
            zlabel(app.UserControlled, 'Z')
            app.UserControlledBisection = uiaxes(app.UIFigure);
            title(app.UserControlledBisection, 'Title')
            xlabel(app.UserControlledBisection, 'X')
            ylabel(app.UserControlledBisection, 'Y')
            zlabel(app.UserControlledBisection, 'Z')
            app.OriginalBisection = uiaxes(app.UIFigure);
            title(app.OriginalBisection, 'Title')
            xlabel(app.OriginalBisection, 'X')
            ylabel(app.OriginalBisection, 'Y')
            zlabel(app.OriginalBisection, 'Z')
            app.UserControlledBisection2 = uiaxes(app.UIFigure);
            title(app.UserControlledBisection2, 'Title')
            xlabel(app.UserControlledBisection2, 'X')
            ylabel(app.UserControlledBisection2, 'Y')
            zlabel(app.UserControlledBisection2, 'Z')
            app.OriginalBisection2 = uiaxes(app.UIFigure);
            title(app.OriginalBisection2, 'Title')
            xlabel(app.OriginalBisection2, 'X')
            ylabel(app.OriginalBisection2, 'Y')
            zlabel(app.OriginalBisection2, 'Z')
            app.EndEffectorAngleDegreesSliderLabel = uilabel(app.UIFigure);
            app.EndEffectorAngleDegreesSliderLabel.Text = 'End Effector Angle [Degrees] ';
            app.EndEffectorAngleDegreesSlider = uislider(app.UIFigure);
            app.EndEffectorAngleDegreesSlider.Limits = [-90 135];
            app.EndEffectorAngleDegreesSlider.MajorTicks = [-90 -45 0 45 90 135];
            app.PlotWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.PlotWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotWorkspaceButtonPushed, true);
            app.PlotWorkspaceButton.Text = 'Plot Workspace';
            app.PlotInverseKinematicsButton = uibutton(app.UIFigure, 'push');
            app.PlotInverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInverseKinematicsButtonPushed, true);
            app.PlotInverseKinematicsButton.Text = 'Plot Inverse Kinematics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OWIWkspaceVisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end